classdef Polos_vs_Comportamiento1S < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PolosVSTemporal             matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        GraphicsButton              matlab.ui.control.Button
        BaseaSliderLabel            matlab.ui.control.Label
        BaseaSlider                 matlab.ui.control.Slider
        CantidadMuestrasNEditFieldLabel  matlab.ui.control.Label
        CantidadMuestrasNEditField  matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global N; %Número de muestras a visualizar
            N=30;
            global a; %parámetro capturado en slider
            a=0;
        end

        % Button pushed function: GraphicsButton
        function GraphicsButtonPushed(app, event)
            clc; 
            global N; %Número de muestras a visualizar
            global a; %parámetro capturado en slider
            
            
            % Señal Exponencial
            n=0:N-1; % Vector Instantes n
            x= a.^n; % Vector señal x(n)
            
            plot(app.UIAxes, n, x,'-o r'); app.UIAxes.Title.String = ['Discrete Signal, a=' num2str(a) ' N=' num2str(N)];
                app.UIAxes.XLabel.String = 'n'; app.UIAxes.YLabel.String = 'x(n)';
                app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on';
           
                
            % Polos y Ceros
            z=[0 ]; %vector columna de ceros
            p=[a]; %vector columna de polos
               
            
            cla(app.UIAxes2); % limpio la imagen para actualizar la gráfica
            zplane(z,p,app.UIAxes2); app.UIAxes2.Title.String ='Polos y Ceros'  
            axis(app.UIAxes2,[-2 2 -2 2]);
            app.UIAxes2.XGrid = 'on'; app.UIAxes2.YGrid = 'on';
           
            
        end

        % Value changed function: BaseaSlider
        function BaseaSliderValueChanged(app, event)
            global a
            
            a = app.BaseaSlider.Value; %Base de la función x(n)=a^n
            
        end

        % Value changed function: CantidadMuestrasNEditField
        function CantidadMuestrasNEditFieldValueChanged(app, event)
            global N
            
            N = app.CantidadMuestrasNEditField.Value; %Número de muestras a visualizar en grafica de x(n)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PolosVSTemporal = uifigure;
            app.PolosVSTemporal.Name = 'Polos vs Tiempo, x(n)=a^n u(n)';
            app.UIAxes = uiaxes(app.PolosVSTemporal);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.PolosVSTemporal);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.GraphicsButton = uibutton(app.PolosVSTemporal, 'push');
            app.GraphicsButton.ButtonPushedFcn = createCallbackFcn(app, @GraphicsButtonPushed, true);
            app.GraphicsButton.Text = 'Graphics';
            app.BaseaSliderLabel = uilabel(app.PolosVSTemporal);
            app.BaseaSliderLabel.Text = 'Base: a';
            app.BaseaSlider = uislider(app.PolosVSTemporal);
            app.BaseaSlider.Limits = [-2 2];
            app.BaseaSlider.MajorTicks = [-2 -1.5 -1 -0.5 0 0.5 1 1.5 2];
            app.BaseaSlider.ValueChangedFcn = createCallbackFcn(app, @BaseaSliderValueChanged, true);
            app.CantidadMuestrasNEditFieldLabel = uilabel(app.PolosVSTemporal);
            app.CantidadMuestrasNEditFieldLabel.Text = 'Cantidad Muestras N';
            app.CantidadMuestrasNEditField = uieditfield(app.PolosVSTemporal, 'numeric');
            app.CantidadMuestrasNEditField.ValueChangedFcn = createCallbackFcn(app, @CantidadMuestrasNEditFieldValueChanged, true);
            app.CantidadMuestrasNEditField.Value = 30;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Polos_vs_Comportamiento1S

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PolosVSTemporal)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PolosVSTemporal)
        end
    end
end