classdef Polos_vs_Comportamiento3S < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PolosvsTiemposUIFigure     matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        GraphicsButton             matlab.ui.control.Button
        SeleccinBaseaSliderLabel   matlab.ui.control.Label
        SeleccinBaseaSlider        matlab.ui.control.Slider
        CantidadMuestrasNEditFieldLabel  matlab.ui.control.Label
        CantidadMuestrasEditField  matlab.ui.control.NumericEditField
        SeleccinSealButtonGroup    matlab.ui.container.ButtonGroup
        x1Button                   matlab.ui.control.RadioButton
        x2Button                   matlab.ui.control.RadioButton
        x3Button                   matlab.ui.control.RadioButton
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global N; %Número de muestras a visualizar
                N=30; %Valor inicial
            global a; %parámetro capturado en slider
                a=0;  %Valor inicial
            global senal; %selecciona una de las señales x(n)
                senal=1; %Valor inicial
        end

        % Button pushed function: GraphicsButton
        function GraphicsButtonPushed(app, event)
            clc; 
            global N; %Número de muestras a visualizar
            global a; %parámetro capturado en slider
            global senal; %selecciona una de las señales x(n)
            
                        
            n=0:N-1; % Vector Instantes n
            
                   
            switch  senal
                case 1  %señal x(n)=a^n u(n)
                    x=  a.^n ; 
                    % Polos y Ceros
                    z=[0 ]; %vector columna de ceros
                    p=[a ]; %vector columna de polos        
                    
                case 2  %señal x(n)=n a^n u(n)
                    x= n.*( a.^n) ; 
                    % Polos y Ceros
                    z=[0; 0 ]; %vector columna de ceros
                    p=[a; a ]; %vector columna de polos
                                       
                case 3  %señal x(n)= a^n cos(w0 n)  u(n)  
                    w0=pi/4;
                    x= (a.^n) .* cos(w0 *n);
                    % Polos y Ceros
                    num= [1  -a*cos(w0) ];
                    den= [1 -2*a*cos(w0)  a^2];
                    z=roots(num);%vector columna de ceros
                    p=roots(den);%vector columna de polos
                                                        
            end 
            
            
            % Visualizar Señal x(n)
            cla(app.UIAxes); % limpia para actualizar la gráfica
            plot(app.UIAxes, n, x,'-o r'); app.UIAxes.Title.String = ['Discrete Signal, a=' num2str(a) ' N=' num2str(N)];
                app.UIAxes.XLabel.String = 'n'; app.UIAxes.YLabel.String = 'x(n)';
                app.UIAxes.XGrid = 'on'; app.UIAxes.YGrid = 'on';


            % Visualizar Diagrama de Polos y Ceros   
            
            cla(app.UIAxes2); % limpia para actualizar la gráfica
            zplane(z,p,app.UIAxes2);  axis(app.UIAxes2,[-2 2 -2 2]);
            app.UIAxes2.XGrid = 'on'; app.UIAxes2.YGrid = 'on';
           
            
        end

        % Value changed function: SeleccinBaseaSlider
        function SeleccinBaseaSliderValueChanged(app, event)
            global a
            
            a = app.SeleccinBaseaSlider.Value; %Base de la función x(n)=a^n
            
        end

        % Value changed function: CantidadMuestrasEditField
        function CantidadMuestrasEditFieldValueChanged(app, event)
            global N
            N = app.CantidadMuestrasEditField.Value; %Número de muestras a visualizar en grafica de x(n)
            
        end

        % Selection changed function: SeleccinSealButtonGroup
        function SeleccinSealButtonGroupSelectionChanged(app, event)
            global senal;  %selecciona señal
            selectedButton = app.SeleccinSealButtonGroup.SelectedObject;
            
            s=selectedButton.Text;
                        
            if  strcmp(s,app.x1Button.Text)
               senal=1;  
                
            elseif strcmp(s,app.x2Button.Text)
              senal=2;  
                
            elseif strcmp(s,app.x3Button.Text)    
              senal=3;  
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PolosvsTiemposUIFigure = uifigure;
            app.PolosvsTiemposUIFigure.Name = 'Polos vs Tiempo  Mutiples Funciones';
            app.UIAxes = uiaxes(app.PolosvsTiemposUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.PolosvsTiemposUIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.GraphicsButton = uibutton(app.PolosvsTiemposUIFigure, 'push');
            app.GraphicsButton.ButtonPushedFcn = createCallbackFcn(app, @GraphicsButtonPushed, true);
            app.GraphicsButton.Text = 'Graphics';
            app.SeleccinBaseaSliderLabel = uilabel(app.PolosvsTiemposUIFigure);
            app.SeleccinBaseaSliderLabel.Text = 'Selección Base: a ';
            app.SeleccinBaseaSlider = uislider(app.PolosvsTiemposUIFigure);
            app.SeleccinBaseaSlider.Limits = [-2 2];
            app.SeleccinBaseaSlider.MajorTicks = [-2 -1.5 -1 -0.5 0 0.5 1 1.5 2];
            app.SeleccinBaseaSlider.ValueChangedFcn = createCallbackFcn(app, @SeleccinBaseaSliderValueChanged, true);
            app.CantidadMuestrasNEditFieldLabel = uilabel(app.PolosvsTiemposUIFigure);
            app.CantidadMuestrasNEditFieldLabel.Text = 'Cantidad Muestras N';
            app.CantidadMuestrasEditField = uieditfield(app.PolosvsTiemposUIFigure, 'numeric');
            app.CantidadMuestrasEditField.Limits = [-200 200];
            app.CantidadMuestrasEditField.ValueChangedFcn = createCallbackFcn(app, @CantidadMuestrasEditFieldValueChanged, true);
            app.CantidadMuestrasEditField.Value = 30;
            app.SeleccinSealButtonGroup = uibuttongroup(app.PolosvsTiemposUIFigure);
            app.SeleccinSealButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SeleccinSealButtonGroupSelectionChanged, true);
            app.SeleccinSealButtonGroup.Title = 'Selección Señal';
            app.x1Button = uiradiobutton(app.SeleccinSealButtonGroup);
            app.x1Button.Text = ' x(n)=a^n u(n)';
            app.x1Button.Value = true;
            app.x2Button = uiradiobutton(app.SeleccinSealButtonGroup);
            app.x2Button.Text = ' x(n)=n a^n u(n)';
            app.x3Button = uiradiobutton(app.SeleccinSealButtonGroup);
            app.x3Button.Text = ' x(n)= a^n cos(wo n) u(n)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Polos_vs_Comportamiento3S

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PolosvsTiemposUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PolosvsTiemposUIFigure)
        end
    end
end