classdef Audio_Processor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SignalOneTab                   matlab.ui.container.Tab
        PanelSignalOne_1               matlab.ui.container.Panel
        UIAxes8                        matlab.ui.control.UIAxes
        UIAxes9                        matlab.ui.control.UIAxes
        PanelSignalOne_2               matlab.ui.container.Panel
        UIAxes10                       matlab.ui.control.UIAxes
        SignalTwoTab                   matlab.ui.container.Tab
        PanelSignalTwo_1               matlab.ui.container.Panel
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes6                        matlab.ui.control.UIAxes
        PanelSignalTwo_2               matlab.ui.container.Panel
        UIAxes7                        matlab.ui.control.UIAxes
        OutResultButtonGroup           matlab.ui.container.ButtonGroup
        SignalResultButton             matlab.ui.control.RadioButton
        PhotogramButton                matlab.ui.control.RadioButton
        AmplitudePanel                 matlab.ui.container.Panel
        AmplitudeSliderLabel           matlab.ui.control.Label
        AmplitudeSlider                matlab.ui.control.Slider
        GainEditFieldLabel             matlab.ui.control.Label
        GainEditField                  matlab.ui.control.NumericEditField
        AmplitudeModeDropDownLabel     matlab.ui.control.Label
        AmplitudeModeDropDown          matlab.ui.control.DropDown
        SignalsOperationsPanel         matlab.ui.container.Panel
        SelectOperationDropDownLabel   matlab.ui.control.Label
        SelectOperationDropDown        matlab.ui.control.DropDown
        hnLabel                        matlab.ui.control.Label
        hnEditField                    matlab.ui.control.NumericEditField
        b_kLabel                       matlab.ui.control.Label
        b_kEditField                   matlab.ui.control.NumericEditField
        a_kLabel                       matlab.ui.control.Label
        a_kEditField                   matlab.ui.control.NumericEditField
        OperationButton                matlab.ui.control.Button
        SelecttypeInPanel              matlab.ui.container.Panel
        Button                         matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        Switch                         matlab.ui.control.Switch
        ChirpButton                    matlab.ui.control.Button
        InvertButton                   matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        SelectAudioLabel               matlab.ui.control.Label
        SelectAudioEditField           matlab.ui.control.EditField
        PauseButton                    matlab.ui.control.Button
        AUDIOPROCESSORPROJECTONELabel  matlab.ui.control.Label
        ConverttoButtonGroup           matlab.ui.container.ButtonGroup
        StereoButton                   matlab.ui.control.RadioButton
        MonoButton                     matlab.ui.control.RadioButton
        ConvertButton                  matlab.ui.control.Button
        SignalResultPanel              matlab.ui.container.Panel
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes3_2                      matlab.ui.control.UIAxes
        SignalResultPanel_2            matlab.ui.container.Panel
        UIAxes4                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Data_audio;
        Data_Audio1;
        Data_Audio2;        
        Fs;
        recorder;
        player;
    end
    
    methods (Access = public)
        
        function GenerateGraph (app,flagIn)
        [m, n] = size(app.Data_audio);
        if n==2 && flagIn==1   
            switch app.TabGroup.SelectedTab.Title
                case 'Signal One'
                    app.PanelSignalOne_2.Visible='off';
                    app.PanelSignalOne_1.Visible='on';
                    plot(app.UIAxes8,app.Data_audio(:,1));
                    plot(app.UIAxes9,app.Data_audio(:,2));
                    drawnow();
                case 'Signal Two'
                    app.PanelSignalTwo_2.Visible='off';
                    app.PanelSignalTwo_1.Visible='on';
                    plot(app.UIAxes5,app.Data_audio(:,1));
                    plot(app.UIAxes6,app.Data_audio(:,2));
                    drawnow();
            end            
        elseif n==1 && flagIn==1
            switch app.TabGroup.SelectedTab.Title
                case 'Signal One'
                    app.PanelSignalOne_2.Visible='on';
                    app.PanelSignalOne_1.Visible='off';
                    plot(app.UIAxes10,app.Data_audio());
                    drawnow();
                case 'Signal Two'
                    app.PanelSignalTwo_2.Visible='on';
                    app.PanelSignalTwo_1.Visible='off';
                    plot(app.UIAxes7,app.Data_audio());
                    drawnow();                    
            end
        elseif  n==2 && flagIn==0
            app.SignalResultPanel_2.Visible='off';
            app.SignalResultPanel.Visible='on';
            plot(app.UIAxes3,app.Data_audio(:,1));
            plot(app.UIAxes3_2,app.Data_audio(:,2));
        else
            app.SignalResultPanel_2.Visible='on';
            app.SignalResultPanel.Visible='off';
            plot(app.UIAxes4,app.Data_audio());           
            
            
        end
        end
        function AsignamentData (app)
            switch app.TabGroup.SelectedTab.Title
                case 'Signal One'
                    
                case 'Signal Two'
                    
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_2
        function ButtonRecord(app, event)
            switch app.Switch.Value
                case 'Mono'
                    Modo = 1
                case 'Stereo'                    
                    Modo = 2
            end
            text_Caja={ 'Frecuencia Muestreo'};
            title_caja='Grabación';
            num_lines = 1;
            defaultans = {'44100'};
            caja =inputdlg(text_Caja,title_caja,num_lines,defaultans);
            app.Fs=str2num(caja{1});%frecuencia de muestreo
            app.recorder = audiorecorder(app.Fs, 24, Modo)
            disp('Start speaking.')
            app.Lamp.Color=[1 0 0];
            app.recorder.record();
            while app.recorder.isrecording()
                pause(0.1);
                app.Data_audio=app.recorder.getaudiodata();
                GenerateGraph(app,1);                
            end
            AsignamentData(app);
        end

        % Callback function
        function SignalOneTabSizeChanged(app, event)
           
        end

        % Button pushed function: Button
        function ButtonOpenFolder(app, event)
            [app.Data_audio,app.Fs, path] =Open_File()
            app.SelectAudioEditField.Value=path;
            GenerateGraph(app,1);
            AsignamentData(app);
        end

        % Button pushed function: Button_3
        function ButtonPlay(app, event)
            app.player = audioplayer(app.Data_audio,app.Fs);
            GenerateGraph(app,0);
            app.player.play();           
        end

        % Callback function
        function SignalTwoTabSizeChanged(app, event)
            
        end

        % Button pushed function: ChirpButton
        function ChirpButtonPushed(app, event)
            app.Data_audio= ObtChirp
            GenerateGraph(app,1);
        end

        % Button pushed function: InvertButton
        function InvertButtonPushed(app, event)
            app.Data_audio=flip(app.Data_audio);
            GenerateGraph(app,0);
            Play_Audio(app.Data_audio,app.Fs);
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            if app.StereoButton.BusyAction
                app.Data_audio=Convert2Mono(app.Data_audio);
                GenerateGraph(app,0);
                Play_Audio(app.Data_audio,app.Fs); 
                
            end            
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            
            if ~isempty(app.recorder)               
                if app.recorder.isrecording()
                    app.recorder.stop();
                    app.Lamp.Color=[0 1 0];
                end
            end        
            if ~isempty(app.player)                
                if app.player.isplaying()
                    stop( app.player);
                    disp("Se hizo el Stop");
                end
            end            
        end

        % Value changed function: SelectOperationDropDown
        function SelectOperationDropDownValueChanged(app, event)
            value = app.SelectOperationDropDown.Value
            switch value
                case 'Add'
                    %verify_frequency();
                    valor=1
                    app.hnEditField.Enable='off';
                    app.b_kEditField.Enable='off';
                    app.a_kEditField.Enable='off';
                case 'Times'
                    valor=2
                    app.hnEditField.Enable='off';
                    app.b_kEditField.Enable='off';
                    app.a_kEditField.Enable='off';
                case 'Rebuild'
                    valor=3 
                    app.hnEditField.Enable='off';
                    app.b_kEditField.Enable='off';
                    app.a_kEditField.Enable='off';               
                case 'IIR'
                    valor=4
                    app.hnEditField.Enable='on';
                    app.b_kEditField.Enable='off';
                    app.a_kEditField.Enable='off';               
                case 'FIR'
                    valor=5
                    app.hnEditField.Enable='off';
                    app.b_kEditField.Enable='on';
                    app.a_kEditField.Enable='on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SignalOneTab = uitab(app.TabGroup);
            app.SignalOneTab.Title = 'Signal One';
            app.PanelSignalOne_1 = uipanel(app.SignalOneTab);
            app.PanelSignalOne_1.Visible = 'off';
            app.UIAxes8 = uiaxes(app.PanelSignalOne_1);
            title(app.UIAxes8, 'Right Channel')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            app.UIAxes9 = uiaxes(app.PanelSignalOne_1);
            title(app.UIAxes9, 'Left Channel')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            app.PanelSignalOne_2 = uipanel(app.SignalOneTab);
            app.UIAxes10 = uiaxes(app.PanelSignalOne_2);
            title(app.UIAxes10, 'Data')
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            app.SignalTwoTab = uitab(app.TabGroup);
            app.SignalTwoTab.Title = 'Signal Two';
            app.PanelSignalTwo_1 = uipanel(app.SignalTwoTab);
            app.UIAxes5 = uiaxes(app.PanelSignalTwo_1);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.UIAxes6 = uiaxes(app.PanelSignalTwo_1);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            app.PanelSignalTwo_2 = uipanel(app.SignalTwoTab);
            app.UIAxes7 = uiaxes(app.PanelSignalTwo_2);
            title(app.UIAxes7, 'Data')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            app.OutResultButtonGroup = uibuttongroup(app.UIFigure);
            app.OutResultButtonGroup.Title = 'Out Result';
            app.SignalResultButton = uiradiobutton(app.OutResultButtonGroup);
            app.SignalResultButton.Text = 'Signal Result ';
            app.SignalResultButton.Value = true;
            app.PhotogramButton = uiradiobutton(app.OutResultButtonGroup);
            app.PhotogramButton.Text = 'Photogram';
            app.AmplitudePanel = uipanel(app.UIFigure);
            app.AmplitudePanel.Title = 'Amplitude';
            app.AmplitudeSliderLabel = uilabel(app.AmplitudePanel);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.AmplitudePanel);
            app.AmplitudeSlider.Value = 1;
            app.GainEditFieldLabel = uilabel(app.AmplitudePanel);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.AmplitudePanel, 'numeric');
            app.GainEditField.Value = 1;
            app.AmplitudeModeDropDownLabel = uilabel(app.AmplitudePanel);
            app.AmplitudeModeDropDownLabel.Text = 'Amplitude Mode';
            app.AmplitudeModeDropDown = uidropdown(app.AmplitudePanel);
            app.AmplitudeModeDropDown.Items = {'Gain', 'Up Auto', 'Down Auto'};
            app.AmplitudeModeDropDown.Value = 'Gain';
            app.SignalsOperationsPanel = uipanel(app.UIFigure);
            app.SignalsOperationsPanel.Title = 'Signals Operations';
            app.SelectOperationDropDownLabel = uilabel(app.SignalsOperationsPanel);
            app.SelectOperationDropDownLabel.Text = 'Select Operation:';
            app.SelectOperationDropDown = uidropdown(app.SignalsOperationsPanel);
            app.SelectOperationDropDown.Items = {'Add', 'Times', 'Rebuild', 'IIR', 'FIR'};
            app.SelectOperationDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectOperationDropDownValueChanged, true);
            app.SelectOperationDropDown.Value = 'Add';
            app.hnLabel = uilabel(app.SignalsOperationsPanel);
            app.hnLabel.Enable = 'off';
            app.hnLabel.Text = 'h(n):';
            app.hnEditField = uieditfield(app.SignalsOperationsPanel, 'numeric');
            app.hnEditField.Enable = 'off';
            app.b_kLabel = uilabel(app.SignalsOperationsPanel);
            app.b_kLabel.Enable = 'off';
            app.b_kLabel.Text = 'b_k:';
            app.b_kEditField = uieditfield(app.SignalsOperationsPanel, 'numeric');
            app.b_kEditField.Enable = 'off';
            app.a_kLabel = uilabel(app.SignalsOperationsPanel);
            app.a_kLabel.Enable = 'off';
            app.a_kLabel.Text = 'a_k:';
            app.a_kEditField = uieditfield(app.SignalsOperationsPanel, 'numeric');
            app.a_kEditField.Enable = 'off';
            app.OperationButton = uibutton(app.SignalsOperationsPanel, 'push');
            app.OperationButton.Icon = 'derecho.png';
            app.OperationButton.Text = '';
            app.SelecttypeInPanel = uipanel(app.UIFigure);
            app.SelecttypeInPanel.Title = 'Select type In';
            app.Button = uibutton(app.SelecttypeInPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonOpenFolder, true);
            app.Button.Icon = 'icons8-música-64.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.SelecttypeInPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonRecord, true);
            app.Button_2.Icon = 'icons8-micrófono-64(1).png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.SelecttypeInPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlay, true);
            app.Button_3.Icon = 'icons8-sony-vegas-64.png';
            app.Button_3.Text = '';
            app.Switch = uiswitch(app.SelecttypeInPanel, 'slider');
            app.Switch.Items = {'Mono', 'Stereo'};
            app.Switch.Value = 'Mono';
            app.ChirpButton = uibutton(app.SelecttypeInPanel, 'push');
            app.ChirpButton.ButtonPushedFcn = createCallbackFcn(app, @ChirpButtonPushed, true);
            app.ChirpButton.Icon = 'icons8-onda-sonora-64.png';
            app.ChirpButton.Text = 'Chirp';
            app.InvertButton = uibutton(app.SelecttypeInPanel, 'push');
            app.InvertButton.ButtonPushedFcn = createCallbackFcn(app, @InvertButtonPushed, true);
            app.InvertButton.Icon = 'flecha-hacia-la-izquierda.png';
            app.InvertButton.Text = 'Invert';
            app.Lamp = uilamp(app.SelecttypeInPanel);
            app.SelectAudioLabel = uilabel(app.SelecttypeInPanel);
            app.SelectAudioLabel.Text = 'Select Audio:';
            app.SelectAudioEditField = uieditfield(app.SelecttypeInPanel, 'text');
            app.PauseButton = uibutton(app.SelecttypeInPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Icon = 'detener.png';
            app.PauseButton.Text = '';
            app.AUDIOPROCESSORPROJECTONELabel = uilabel(app.UIFigure);
            app.AUDIOPROCESSORPROJECTONELabel.Text = 'AUDIO PROCESSOR PROJECT ONE';
            app.ConverttoButtonGroup = uibuttongroup(app.UIFigure);
            app.ConverttoButtonGroup.Title = 'Convert to';
            app.StereoButton = uiradiobutton(app.ConverttoButtonGroup);
            app.StereoButton.Text = 'Stereo';
            app.StereoButton.Value = true;
            app.MonoButton = uiradiobutton(app.ConverttoButtonGroup);
            app.MonoButton.Text = 'Mono';
            app.ConvertButton = uibutton(app.ConverttoButtonGroup, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Icon = 'derecho.png';
            app.ConvertButton.Text = '';
            app.SignalResultPanel = uipanel(app.UIFigure);
            app.SignalResultPanel.Title = 'Signal Result';
            app.UIAxes3 = uiaxes(app.SignalResultPanel);
            title(app.UIAxes3, 'Right Channel')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3_2 = uiaxes(app.SignalResultPanel);
            title(app.UIAxes3_2, 'Left Channel')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            app.SignalResultPanel_2 = uipanel(app.UIFigure);
            app.SignalResultPanel_2.Title = 'Signal Result';
            app.UIAxes4 = uiaxes(app.SignalResultPanel_2);
            title(app.UIAxes4, 'Data')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Audio_Processor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end