classdef UI_DSP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Tarea_01_DSPUIFigure  matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        A_X1EditFieldLabel    matlab.ui.control.Label
        A_X1                  matlab.ui.control.NumericEditField
        GraficarButton_X1     matlab.ui.control.Button
        ni_X1EditFieldLabel   matlab.ui.control.Label
        ni_X1                 matlab.ui.control.NumericEditField
        nf_X1EditFieldLabel   matlab.ui.control.Label
        nf_X1                 matlab.ui.control.NumericEditField
        no_X1EditFieldLabel   matlab.ui.control.Label
        no_X1                 matlab.ui.control.NumericEditField
        A_X2EditFieldLabel    matlab.ui.control.Label
        A_X2                  matlab.ui.control.NumericEditField
        ni_X2EditFieldLabel   matlab.ui.control.Label
        ni_X2                 matlab.ui.control.NumericEditField
        nf_X2EditFieldLabel   matlab.ui.control.Label
        nf_X2                 matlab.ui.control.NumericEditField
        no_X2EditFieldLabel   matlab.ui.control.Label
        no_X2                 matlab.ui.control.NumericEditField
        GraficarButton_X2     matlab.ui.control.Button
        UIAxesX2              matlab.ui.control.UIAxes
        X1nListBoxLabel       matlab.ui.control.Label
        X1nListBox            matlab.ui.control.ListBox
        X2nListBoxLabel       matlab.ui.control.Label
        X2nListBox            matlab.ui.control.ListBox
        OperacinListBoxLabel  matlab.ui.control.Label
        OperacinListBox       matlab.ui.control.ListBox
        UIAxes3               matlab.ui.control.UIAxes
        GraficarButton_op     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton_X1
        function GraficarButton_X1Pushed(app, event)
            %hola           

            
            A=app.A_X1.Value; % Amplitud
            ni=app.ni_X1.Value; %Instante de inicio
            nf=app.nf_X1.Value;  % Instante final
            x=A*[zeros(1,abs(ni)) 1 zeros(1,abs(nf))]; % Señal impulso
            n=ni:nf;  % Instantes de tiempo
            %subplot(2,2,1);
            
            
            stem(app.UIAxes,n,x); 
            
           
            value =  app.X1nListBox.Value;

            switch value
                case 'Dirac'
                    x=A*[zeros(1,abs(ni)) 1 zeros(1,abs(nf))];
                    stem(app.UIAxes,n,x); 
                case 'Escalon'
                    x= A*[zeros(1,abs(ni))  ones(1, abs(nf)+1)  ]; 
                    stem(app.UIAxes,n,x); 
                case 'Rampa'
                    x=A*n;
                    stem(app.UIAxes,n,x);                     
                case 'Exponencial Base Real'
                    a=3
                    x= A*a.^n;
                    stem(app.UIAxes,n,x); 
                case 'Exponencial Base Imaginaria'
                    a=3
                    b=5+1i*0.5
                    x= A*b.^n;
                    
                    x_real= real(x);
                    x_imaginario= imag(x);
                    x_magnitud= abs(x);
                    x_angulo= angle(x);
                    
                    
                    stem(app.UIAxes,n,x_imaginario); 
                   
                    
                case 'Senosoidal'
                    
                    F= 20;   % Frecuencia análoga
                    Fs=415; % Frecuencia de muestreo
                    Ts=1/Fs;
                    n=ni:nf;
                    x= A*sin(2*pi*F*n/Fs);
                    stem(app.UIAxes,n,x);    
                    
                case 'Chirp'
                    omega = 2
                    s_t = n.^2/4
                    chirp_man=A*cos(omega*n+s_t)
                    stem(app.UIAxes,n,chirp_man)
                    
                    
            end
           
            
        end

        % Value changed function: X1nListBox
        function X1nListBoxValueChanged(app, event)
            value = app.X1nListBox.Value;
            
        end

        % Button pushed function: GraficarButton_X2
        function GraficarButton_X2Pushed(app, event)
            %hola2
            
            A=app.A_X2.Value; % Amplitud
            ni=app.ni_X2.Value; %Instante de inicio
            nf=app.nf_X2.Value;  % Instante final
            x=A*[zeros(1,abs(ni)) 1 zeros(1,abs(nf))]; % Señal impulso
            n=ni:nf;  % Instantes de tiempo
            %subplot(2,2,1);
            
            
            stem(app.UIAxesX2,n,x); 
            
           
            value_2 =  app.X2nListBox.Value;

            switch value_2
                case 'Dirac'
                    x=A*[zeros(1,abs(ni)) 1 zeros(1,abs(nf))];
                    stem(app.UIAxesX2,n,x); 
                case 'Escalon'
                    x= A*[zeros(1,abs(ni))  ones(1, abs(nf)+1)  ]; 
                    stem(app.UIAxesX2,n,x); 
                case 'Rampa'
                    x=A*n;
                    stem(app.UIAxesX2,n,x);                     
                case 'Exponencial Base Real'
                    a=3
                    x= A*a.^n;
                    stem(app.UIAxesX2,n,x); 
                case 'Exponencial Base Imaginaria'
                    a=3
                    b=5+1i*0.5
                    x= A*b.^n;
                    
                    x_real= real(x);
                    x_imaginario= imag(x);
                    x_magnitud= abs(x);
                    x_angulo= angle(x);
                    
                    
                    stem(app.UIAxesX2,n,x_imaginario); 
                   
                    
                case 'Senosoidal'
                    
                    F= 20;   % Frecuencia análoga
                    Fs=415; % Frecuencia de muestreo
                    Ts=1/Fs;
                    n=ni:nf;
                    x= A*sin(2*pi*F*n/Fs);
                    stem(app.UIAxesX2,n,x);    
                    
                case 'Chirp'
                    omega = 2
                    s_t = n.^2/4
                    chirp_man=A*cos(omega*n+s_t)
                    stem(app.UIAxesX2,n,chirp_man)
                    
                    
            end
        end

        % Button pushed function: GraficarButton_op
        function GraficarButton_opPushed(app, event)
            %hola 3
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Tarea_01_DSPUIFigure = uifigure('Visible', 'off');
            app.Tarea_01_DSPUIFigure.Name = 'Tarea_01_DSP';
            app.UIAxes = uiaxes(app.Tarea_01_DSPUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.A_X1EditFieldLabel = uilabel(app.Tarea_01_DSPUIFigure);
            app.A_X1EditFieldLabel.Text = 'A_X1';
            app.A_X1 = uieditfield(app.Tarea_01_DSPUIFigure, 'numeric');
            app.GraficarButton_X1 = uibutton(app.Tarea_01_DSPUIFigure, 'push');
            app.GraficarButton_X1.ButtonPushedFcn = createCallbackFcn(app, @GraficarButton_X1Pushed, true);
            app.GraficarButton_X1.Text = 'Graficar';
            app.ni_X1EditFieldLabel = uilabel(app.Tarea_01_DSPUIFigure);
            app.ni_X1EditFieldLabel.Text = 'ni_X1';
            app.ni_X1 = uieditfield(app.Tarea_01_DSPUIFigure, 'numeric');
            app.nf_X1EditFieldLabel = uilabel(app.Tarea_01_DSPUIFigure);
            app.nf_X1EditFieldLabel.Text = 'nf_X1';
            app.nf_X1 = uieditfield(app.Tarea_01_DSPUIFigure, 'numeric');
            app.no_X1EditFieldLabel = uilabel(app.Tarea_01_DSPUIFigure);
            app.no_X1EditFieldLabel.Text = 'no_X1';
            app.no_X1 = uieditfield(app.Tarea_01_DSPUIFigure, 'numeric');
            app.A_X2EditFieldLabel = uilabel(app.Tarea_01_DSPUIFigure);
            app.A_X2EditFieldLabel.Text = 'A_X2';
            app.A_X2 = uieditfield(app.Tarea_01_DSPUIFigure, 'numeric');
            app.ni_X2EditFieldLabel = uilabel(app.Tarea_01_DSPUIFigure);
            app.ni_X2EditFieldLabel.Text = 'ni_X2';
            app.ni_X2 = uieditfield(app.Tarea_01_DSPUIFigure, 'numeric');
            app.nf_X2EditFieldLabel = uilabel(app.Tarea_01_DSPUIFigure);
            app.nf_X2EditFieldLabel.Text = 'nf_X2';
            app.nf_X2 = uieditfield(app.Tarea_01_DSPUIFigure, 'numeric');
            app.no_X2EditFieldLabel = uilabel(app.Tarea_01_DSPUIFigure);
            app.no_X2EditFieldLabel.Text = 'no_X2';
            app.no_X2 = uieditfield(app.Tarea_01_DSPUIFigure, 'numeric');
            app.GraficarButton_X2 = uibutton(app.Tarea_01_DSPUIFigure, 'push');
            app.GraficarButton_X2.ButtonPushedFcn = createCallbackFcn(app, @GraficarButton_X2Pushed, true);
            app.GraficarButton_X2.Text = 'Graficar';
            app.UIAxesX2 = uiaxes(app.Tarea_01_DSPUIFigure);
            title(app.UIAxesX2, 'Title')
            xlabel(app.UIAxesX2, 'X')
            ylabel(app.UIAxesX2, 'Y')
            app.X1nListBoxLabel = uilabel(app.Tarea_01_DSPUIFigure);
            app.X1nListBoxLabel.Text = 'X1[n]';
            app.X1nListBox = uilistbox(app.Tarea_01_DSPUIFigure);
            app.X1nListBox.Items = {'Dirac', 'Escalon', 'Rampa', 'Exponencial Base Real', 'Exponencial Base Imaginaria', 'Senosoidal', 'Chirp'};
            app.X1nListBox.ValueChangedFcn = createCallbackFcn(app, @X1nListBoxValueChanged, true);
            app.X1nListBox.Value = 'Dirac';
            app.X2nListBoxLabel = uilabel(app.Tarea_01_DSPUIFigure);
            app.X2nListBoxLabel.Text = 'X2[n]';
            app.X2nListBox = uilistbox(app.Tarea_01_DSPUIFigure);
            app.X2nListBox.Items = {'Dirac', 'Escalon', 'Rampa', 'Exponencial Base Real', 'Exponencial Base Imaginaria', 'Senosoidal', 'Chirp', ''};
            app.X2nListBox.Value = 'Dirac';
            app.OperacinListBoxLabel = uilabel(app.Tarea_01_DSPUIFigure);
            app.OperacinListBoxLabel.Text = 'Operación';
            app.OperacinListBox = uilistbox(app.Tarea_01_DSPUIFigure);
            app.OperacinListBox.Items = {'Suma', 'Multiplicación', 'División ', 'Exponenciación', 'Logaritmo'};
            app.OperacinListBox.Value = 'Suma';
            app.UIAxes3 = uiaxes(app.Tarea_01_DSPUIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.GraficarButton_op = uibutton(app.Tarea_01_DSPUIFigure, 'push');
            app.GraficarButton_op.ButtonPushedFcn = createCallbackFcn(app, @GraficarButton_opPushed, true);
            app.GraficarButton_op.Text = 'Graficar';
            % Show the figure after all components are created
            app.Tarea_01_DSPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_DSP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Tarea_01_DSPUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Tarea_01_DSPUIFigure)
        end
    end
end